classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        S2TUIFigure                 matlab.ui.Figure
        Menu                        matlab.ui.container.Menu
        Menu_2                      matlab.ui.container.Menu
        Menu_3                      matlab.ui.container.Menu
        Panel                       matlab.ui.container.Panel
        Label                       matlab.ui.control.Label
        EditFieldFilePath           matlab.ui.control.EditField
        Button                      matlab.ui.control.Button
        Label_2                     matlab.ui.control.Label
        EditFieldStartRow           matlab.ui.control.NumericEditField
        Button_2                    matlab.ui.control.Button
        Label_3                     matlab.ui.control.Label
        EditFieldDampRatio          matlab.ui.control.NumericEditField
        UITable                     matlab.ui.control.Table
        Panel_2                     matlab.ui.container.Panel
        Label_4                     matlab.ui.control.Label
        EditFieldScaleFactor        matlab.ui.control.NumericEditField
        Label_5                     matlab.ui.control.Label
        EditFieldPositiveTolerance  matlab.ui.control.NumericEditField
        Label_6                     matlab.ui.control.Label
        EditFieldNegativeTolerance  matlab.ui.control.NumericEditField
        sEditFieldLabel             matlab.ui.control.Label
        EditFieldTotalTime          matlab.ui.control.NumericEditField
        sEditField_2Label           matlab.ui.control.Label
        EditFieldRaiseTime          matlab.ui.control.NumericEditField
        sEditField_3Label           matlab.ui.control.Label
        EditFieldStableTime         matlab.ui.control.NumericEditField
        sEditField_4Label           matlab.ui.control.Label
        EditFieldDescendTime        matlab.ui.control.NumericEditField
        Label_7                     matlab.ui.control.Label
        EditFieldDescendFactor      matlab.ui.control.NumericEditField
        Panel_3                     matlab.ui.container.Panel
        Button_3                    matlab.ui.control.Button
        Button_4                    matlab.ui.control.Button
        Switch                      matlab.ui.control.Switch
        Panel_4                     matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        FreqSeries % Description
        SpectralSeries
        GeneratedSpectralValues
        TimeSeries
        AccelarationSeries
        
    end


    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            [file,path] = uigetfile('*.*','选择响应谱文件');
            if isequal(file,0)
                return;
            end
            app.EditFieldFilePath.Value=[path,file];
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            if ~exist(app.EditFieldFilePath.Value,'file')
                uialert(app.S2TUIFigure,'File not found!','Invalid File');
                return;
            end
            try
                [app.FreqSeries,app.SpectralSeries]=importfile(app.EditFieldFilePath.Value,app.EditFieldStartRow.Value);                               
            catch 
                uialert(app.S2TUIFigure,'导入失败,请检查文件！','导入失败');
                return;
            end
            if ~isequal(length(app.FreqSeries),length(app.SpectralSeries))
               app.FreqSeries=[];
               app.SpectralSeries=[];
               uialert(app.S2TUIFigure,'导入失败,数据长度不一致！','导入失败');
               return;
            end
            plot(app.UIAxes,app.FreqSeries,app.SpectralSeries);
            app.UITable.Data=[app.FreqSeries,app.SpectralSeries];
            
        end

        % Value changed function: EditFieldTotalTime
        function EditFieldTotalTimeValueChanged(app, event)
            valueTotalTime = app.EditFieldTotalTime.Value;
            valueRaiseTime=app.EditFieldRaiseTime.Value;
            valueStableTime=app.EditFieldStableTime.Value;
            if valueTotalTime>=valueRaiseTime+valueStableTime
                app.EditFieldDescendTime.Value=valueTotalTime-valueRaiseTime-valueStableTime;
            elseif valueTotalTime>=valueStableTime
                app.EditFieldDescendTime.Value=0;
                app.EditFieldRaiseTime.Value=valueTotalTime-valueStableTime;
            else
                app.EditFieldStableTime.Value=valueTotalTime;
                app.EditFieldRaiseTime.Value=0;
                app.EditFieldDescendTime.Value=0;
            end
        end

        % Value changed function: EditFieldRaiseTime
        function EditFieldRaiseTimeValueChanged(app, event)
            valueTotalTime = app.EditFieldTotalTime.Value;
            valueRaiseTime=app.EditFieldRaiseTime.Value;
            valueStableTime=app.EditFieldStableTime.Value;
            if valueTotalTime>=valueRaiseTime+valueStableTime
                app.EditFieldDescendTime.Value=valueTotalTime-valueRaiseTime-valueStableTime;
            elseif valueTotalTime>=valueRaiseTime
                app.EditFieldDescendTime.Value=0;
                app.EditFieldStableTime.Value=valueTotalTime-valueRaiseTime;
            else
                app.EditFieldStableTime.Value=0;
                app.EditFieldTotalTime.Value=valueRaiseTime;
                app.EditFieldDescendTime.Value=0;
            end
        end

        % Value changed function: EditFieldStableTime
        function EditFieldStableTimeValueChanged(app, event)
            valueTotalTime = app.EditFieldTotalTime.Value;
            valueRaiseTime=app.EditFieldRaiseTime.Value;
            valueStableTime=app.EditFieldStableTime.Value;
            if valueTotalTime>=valueRaiseTime+valueStableTime
                app.EditFieldDescendTime.Value=valueTotalTime-valueRaiseTime-valueStableTime;
            elseif valueTotalTime>=valueStableTime
                app.EditFieldDescendTime.Value=0;
                app.EditFieldRaiseTime.Value=valueTotalTime-valueStableTime;
            else
                app.EditFieldTotalTime.Value=valueStableTime;
                app.EditFieldRaiseTime.Value=0;
                app.EditFieldDescendTime.Value=0;
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            if length(app.FreqSeries)<1
                uialert(app.S2TUIFigure,'先确认反应谱','提示');
                return;
            end
            dampRatio=app.EditFieldDampRatio.Value;
            positiveTolerance=app.EditFieldPositiveTolerance.Value;
            negativeTolerance=app.EditFieldNegativeTolerance.Value;
            scale=app.EditFieldScaleFactor.Value;
            valueTotalTime = app.EditFieldTotalTime.Value;
            valueRaiseTime=app.EditFieldRaiseTime.Value;
            valueStableTime=app.EditFieldStableTime.Value;
            descendFactor=app.EditFieldDescendFactor.Value;
            app.Button_3.BackgroundColor
            [app.AccelarationSeries,app.TimeSeries]=S2TFFT(app.FreqSeries,app.SpectralSeries,dampRatio,scale,positiveTolerance,negativeTolerance,valueTotalTime,valueRaiseTime,valueStableTime,descendFactor);
            if length(app.AccelarationSeries)<1
                uialert(app.S2TUIFigure,'生成失败，可尝试重新求解！','失败');
                return;
            end
            app.GeneratedSpectralValues=tts3(dampRatio,app.FreqSeries,app.AccelarationSeries,app.TimeSeries(2)-app.TimeSeries(1));
            if isequal(app.Switch.Value,'时程')
                plot(app.UIAxes,app.TimeSeries,app.AccelarationSeries);
                app.UIAxes.Title.String='加速度时程';
                app.UIAxes.XLabel.String='时间(s)';
                app.UIAxes.YLabel.String='幅值';
            else 
                plot(app.UIAxes,app.FreqSeries,app.SpectralSeries);
                hold(app.UIAxes);
                plot(app.UIAxes,app.FreqSeries,app.GeneratedSpectralValues);
                plot(app.UIAxes,app.FreqSeries,app.SpectralSeries*scale);
                hold(app.UIAxes,'off');
                grid(app.UIAxes,'on');
                app.UIAxes.Title.String='反应谱';
                app.UIAxes.XLabel.String ='频率(Hz)';
                app.UIAxes.YLabel.String='幅值';
            end
        end

        % Callback function
        function SwitchValueChanged(app, event)
            if length(app.AccelarationSeries)<1
                return;
            end
            value = app.Switch.Value;
            scale=app.EditFieldScaleFactor.Value;
            if isequal(value,'时程')
                plot(app.UIAxes,app.TimeSeries,app.AccelarationSeries);
                app.UIAxes.Title.String='加速度时程';
                app.UIAxes.XLabel.String='时间(s)';
                app.UIAxes.YLabel.String='幅值';
                grid(app.UIAxes,'on');
            else 
                plot(app.UIAxes,app.FreqSeries,app.SpectralSeries);
                hold(app.UIAxes);
                plot(app.UIAxes,app.FreqSeries,app.GeneratedSpectralValues);
                plot(app.UIAxes,app.FreqSeries,app.SpectralSeries*scale);
                hold(app.UIAxes,'off');
                grid(app.UIAxes,'on');
                app.UIAxes.Title.String='反应谱';
                app.UIAxes.XLabel.String ='频率(Hz)';
                app.UIAxes.YLabel.String='幅值';
            end
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            if length(app.AccelarationSeries)<1
                return;
            end
            [filename,filepath]=uiputfile({'*.csv','*.csv'});
            if isequal(filename,0)
                return;
            end
           try
                fullpath=[filepath,filename];
                fid=fopen(fullpath,'wt');
                temp=[app.TimeSeries;app.AccelarationSeries'];
                fprintf(fid,'%f,%f\n',temp);
           catch
                fclose all;
                uiwait(msgbox('导出失败，请检查数据！','modal'));
                return
            end
            fclose all;
            uiwait(msgbox('导出完成！','modal'));
        end

        % Value changed function: Switch
        function SwitchValueChanged2(app, event)
            value = app.Switch.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.S2TUIFigure = uifigure;
            app.S2TUIFigure.Name = 'S2T';
            app.Menu = uimenu(app.S2TUIFigure);
            app.Menu.Text = '文件';
            app.Menu_2 = uimenu(app.Menu);
            app.Menu_2.Text = '退出';
            app.Menu_3 = uimenu(app.S2TUIFigure);
            app.Menu_3.Text = '关于';
            app.Panel = uipanel(app.S2TUIFigure);
            app.Panel.Title = '反应谱';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '文件路径：';
            app.EditFieldFilePath = uieditfield(app.Panel, 'text');
            app.Button = uibutton(app.Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '选择...';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '起始行：';
            app.EditFieldStartRow = uieditfield(app.Panel, 'numeric');
            app.EditFieldStartRow.Limits = [1 Inf];
            app.EditFieldStartRow.ValueDisplayFormat = '%.0f';
            app.EditFieldStartRow.Value = 1;
            app.Button_2 = uibutton(app.Panel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '导入';
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '阻尼比：';
            app.EditFieldDampRatio = uieditfield(app.Panel, 'numeric');
            app.EditFieldDampRatio.Limits = [0 Inf];
            app.EditFieldDampRatio.Value = 0.02;
            app.UITable = uitable(app.Panel);
            app.UITable.ColumnName = {'频率'; '幅值'};
            app.UITable.RowName = {};
            app.Panel_2 = uipanel(app.S2TUIFigure);
            app.Panel_2.Title = '时程设置';
            app.Label_4 = uilabel(app.Panel_2);
            app.Label_4.Text = '反应谱放大系数：';
            app.EditFieldScaleFactor = uieditfield(app.Panel_2, 'numeric');
            app.EditFieldScaleFactor.Limits = [0.01 Inf];
            app.EditFieldScaleFactor.Value = 1.1;
            app.Label_5 = uilabel(app.Panel_2);
            app.Label_5.Text = '正容差：';
            app.EditFieldPositiveTolerance = uieditfield(app.Panel_2, 'numeric');
            app.EditFieldPositiveTolerance.Limits = [1e-05 Inf];
            app.EditFieldPositiveTolerance.Value = 0.05;
            app.Label_6 = uilabel(app.Panel_2);
            app.Label_6.Text = '负容差：';
            app.EditFieldNegativeTolerance = uieditfield(app.Panel_2, 'numeric');
            app.EditFieldNegativeTolerance.Limits = [0 Inf];
            app.EditFieldNegativeTolerance.Value = 0.09;
            app.sEditFieldLabel = uilabel(app.Panel_2);
            app.sEditFieldLabel.Text = '总时长(s)：';
            app.EditFieldTotalTime = uieditfield(app.Panel_2, 'numeric');
            app.EditFieldTotalTime.Limits = [0 Inf];
            app.EditFieldTotalTime.ValueChangedFcn = createCallbackFcn(app, @EditFieldTotalTimeValueChanged, true);
            app.EditFieldTotalTime.Value = 40;
            app.sEditField_2Label = uilabel(app.Panel_2);
            app.sEditField_2Label.Text = '上升段时长(s)：';
            app.EditFieldRaiseTime = uieditfield(app.Panel_2, 'numeric');
            app.EditFieldRaiseTime.Limits = [0 Inf];
            app.EditFieldRaiseTime.ValueChangedFcn = createCallbackFcn(app, @EditFieldRaiseTimeValueChanged, true);
            app.EditFieldRaiseTime.Value = 10;
            app.sEditField_3Label = uilabel(app.Panel_2);
            app.sEditField_3Label.Text = '平稳段时长(s)：';
            app.EditFieldStableTime = uieditfield(app.Panel_2, 'numeric');
            app.EditFieldStableTime.Limits = [0 Inf];
            app.EditFieldStableTime.ValueChangedFcn = createCallbackFcn(app, @EditFieldStableTimeValueChanged, true);
            app.EditFieldStableTime.Value = 24;
            app.sEditField_4Label = uilabel(app.Panel_2);
            app.sEditField_4Label.Text = '下降段时长(s)：';
            app.EditFieldDescendTime = uieditfield(app.Panel_2, 'numeric');
            app.EditFieldDescendTime.Limits = [0 Inf];
            app.EditFieldDescendTime.Editable = 'off';
            app.EditFieldDescendTime.Value = 6;
            app.Label_7 = uilabel(app.Panel_2);
            app.Label_7.Text = '下降指数：';
            app.EditFieldDescendFactor = uieditfield(app.Panel_2, 'numeric');
            app.EditFieldDescendFactor.Limits = [0 Inf];
            app.EditFieldDescendFactor.Value = 0.6;
            app.Panel_3 = uipanel(app.S2TUIFigure);
            app.Panel_3.Title = '求解';
            app.Button_3 = uibutton(app.Panel_3, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '求解';
            app.Button_4 = uibutton(app.Panel_3, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '导出';
            app.Switch = uiswitch(app.Panel_3, 'slider');
            app.Switch.Items = {'时程', '响应谱'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged2, true);
            app.Switch.Value = '时程';
            app.Panel_4 = uipanel(app.S2TUIFigure);
            app.Panel_4.Title = '曲线';
            app.UIAxes = uiaxes(app.Panel_4);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.S2TUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.S2TUIFigure)
        end
    end
end